classdef visualizeAppReg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ComparetwosetstoolsTab         matlab.ui.container.Tab
        Dataset1SliderLabel            matlab.ui.control.Label
        Dataset1Slider                 matlab.ui.control.Slider
        Dataset2SliderLabel            matlab.ui.control.Label
        Dataset2Slider                 matlab.ui.control.Slider
        Dataset1Slider_2Label          matlab.ui.control.Label
        Dataset1Slider_2               matlab.ui.control.Slider
        Dataset2Slider_2Label          matlab.ui.control.Label
        Dataset2Slider_2               matlab.ui.control.Slider
        MarkersizeSliderLabel          matlab.ui.control.Label
        MarkersizeSlider               matlab.ui.control.Slider
        NumberofneuronsLogarithmicscaleLabel  matlab.ui.control.Label
        AbsolutevalueofregressorLabel  matlab.ui.control.Label
        CommonpointsmarkersizeSliderLabel  matlab.ui.control.Label
        CommonpointsmarkersizeSlider   matlab.ui.control.Slider
        CheckdensitiesofbothsetstoolsTab  matlab.ui.container.Tab
        DensitytoplotforButtonGroup    matlab.ui.container.ButtonGroup
        Dataset1Button                 matlab.ui.control.RadioButton
        Dataset2Button                 matlab.ui.control.RadioButton
        GaussianizeSliderLabel         matlab.ui.control.Label
        GaussianizeSlider              matlab.ui.control.Slider
        Quantileforisovalue1EditFieldLabel  matlab.ui.control.Label
        Quantileforisovalue1EditField  matlab.ui.control.NumericEditField
        Quantileforisovalue2EditFieldLabel  matlab.ui.control.Label
        Quantileforisovalue2EditField  matlab.ui.control.NumericEditField
        Quantileforisovalue3EditFieldLabel  matlab.ui.control.Label
        Quantileforisovalue3EditField  matlab.ui.control.NumericEditField
        Isovalue1EditFieldLabel        matlab.ui.control.Label
        Isovalue1EditField             matlab.ui.control.NumericEditField
        Isovalue2EditFieldLabel        matlab.ui.control.Label
        Isovalue2EditField             matlab.ui.control.NumericEditField
        Isovalue3EditFieldLabel        matlab.ui.control.Label
        Isovalue3EditField             matlab.ui.control.NumericEditField
        DFFregressorplottoolsTab       matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        XselectionSliderLabel          matlab.ui.control.Label
        XselectionSlider               matlab.ui.control.Slider
        ZselectionSliderLabel          matlab.ui.control.Label
        ZselectionSlider               matlab.ui.control.Slider
        YselectionSliderLabel          matlab.ui.control.Label
        YselectionSlider               matlab.ui.control.Slider
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        Examplefromset1DropDownLabel   matlab.ui.control.Label
        Examplefromset1DropDown        matlab.ui.control.DropDown
        Examplefromset2DropDownLabel   matlab.ui.control.Label
        Examplefromset2DropDown        matlab.ui.control.DropDown
        LabelsListBox                  matlab.ui.control.ListBox
        LabelsListBox_2                matlab.ui.control.ListBox
        StimulusDropDownLabel          matlab.ui.control.Label
        StimulusDropDown               matlab.ui.control.DropDown
        StimulusDropDown_2Label        matlab.ui.control.Label
        StimulusDropDown_2             matlab.ui.control.DropDown
        YupButton                      matlab.ui.control.Button
        YdownButton                    matlab.ui.control.Button
        XdownButton                    matlab.ui.control.Button
        XupButton                      matlab.ui.control.Button
        ZupButton                      matlab.ui.control.Button
        ZdownButton                    matlab.ui.control.Button
        PlotbrainzonesTab              matlab.ui.container.Tab
        PlotscatteredoutlinesofzonesCheckBox  matlab.ui.control.CheckBox
        PlotXYcontoursofzonesCheckBox  matlab.ui.control.CheckBox
        ChangecolorsetButton           matlab.ui.control.Button
        PlotthefollowingbrainzonesListBoxLabel  matlab.ui.control.Label
        PlotthefollowingbrainzonesListBox  matlab.ui.control.ListBox
        presscontroltopickmultipleLabel  matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
        PlotwholebrainButton           matlab.ui.control.Button
        PlotYZcontoursofzonesCheckBox  matlab.ui.control.CheckBox
        PlotXZcontoursofzonesCheckBox  matlab.ui.control.CheckBox
        SamecolorButton                matlab.ui.control.Button
        AnalysistoolsTab               matlab.ui.container.Tab
        RadiusSliderLabel              matlab.ui.control.Label
        RadiusSlider                   matlab.ui.control.Slider
        SaveindexofclosestpointsforeachsetinworkspaceButton  matlab.ui.control.Button
        SaveindexofplottedneuronsButton  matlab.ui.control.Button
        PlotaveragedresponsetothesestimuliwithinradiusButton  matlab.ui.control.Button
        ProgressEditFieldLabel         matlab.ui.control.Label
        ProgressEditField              matlab.ui.control.NumericEditField
        LoadZBraingridobjectButton     matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        ListofcommentsDropDownLabel    matlab.ui.control.Label
        ListofcommentsDropDown         matlab.ui.control.DropDown
        ListofkeywordsDropDownLabel    matlab.ui.control.Label
        ListofkeywordsDropDown         matlab.ui.control.DropDown
        Keywordsfordataset1usecommastoseparateEditFieldLabel  matlab.ui.control.Label
        Keywordsfordataset1usecommastoseparateEditField  matlab.ui.control.EditField
        Keywordsfordataset2usecommastoseparateEditFieldLabel  matlab.ui.control.Label
        Keywordsfordataset2usecommastoseparateEditField  matlab.ui.control.EditField
        PleasechoserepresentationoptionLabel  matlab.ui.control.Label
        PleasechoserepresentationoptionListBox  matlab.ui.control.ListBox
        UIAxes                         matlab.ui.control.UIAxes
        ControlsPanel                  matlab.ui.container.Panel
        RotateButton                   matlab.ui.control.Button
        XZButton                       matlab.ui.control.Button
        XYButton                       matlab.ui.control.Button
        YZButton                       matlab.ui.control.Button
        ZoomButton                     matlab.ui.control.Button
        ReinitializeButton             matlab.ui.control.Button
        PlotonlycommongridpointsCheckBox  matlab.ui.control.CheckBox
    end


    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    properties (Access = public)
        
        % ZBraingrid objects, and related:
        zgrid % Data loaded through loading button, should be a ZBraingrid object
        zgridpath % Path to data, to be indicated in text zone
        regions % Brain regions to plot
        zoutlines % Outlines from MaskDatabase
        OutplotS % Outlines for main zones: scattered outlines
        OutplotC % Outlines for main zones: contours
        acolor % Color for outlines 
        
        % Information on the two subsets:
        search1 % Keywords for subset 1
        search2 % Keywords for subset 2
        zindex1 % Index for dataset1 in zgrid
        zindex2 % Index for dataset2 in zgrid
        zset1 % ZBraingrid object containing datasets selected with keywords 1
        zset2 % ZBraingrid object containing datasets selected with keywords 2
        nneu1 % Number of neurons for dataset 1
        nneu2 % Number of neurons for dataset 2
        zcobj1 % Sorted correlations vector for dataset 1
        zcobj2 % Sorted correlations vector for dataset 2
        maval1 % Maximum regressor absolute value for dataset 1
        maval2 % Maximum regressor absolute value for dataset 2
        
        % Plot options:
        ptool % Tool for plot, can be 1, 2, or 3
        mrksize % Marker size for plot options 1 and 3
        cmrksize % Marker size for common points plot options 1 and 3
        arotate % To rotate figure
        azoom % To zoom in figure
        aview % Rotation of plot
        alim % Limits for the axes
        
        % Plot options for isovalues:
        whichset % Which set to plot density
        gauss % Gaussianization parameter
        isoval1 % First isovalue
        isoval2 % Second isovalue
        isoval3 % Third isovalue
        
        % Informations for DFF:
        ind2labels % Index to labels matrix
        mypoint % Selected point to display
        selpoint1 % Closest point to mypoint in set 1
        selpoint2 % Closest point to mypoint in set 2
        dexample1 % Datasets associated to set1 comprising selpoint1
        dexample2 % Datasets associated to set2 comprising selpoint2
        choicex1 % Choice in dexample1 to plot for set 1
        choicex2 % Choice in dexample2 to plot for set 2
        epath1 % Stimulus to plot for set 1
        epath2 % Stimulus to plot for set 2
        
    end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    methods (Access = private)
    
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
    end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add path to class:
            addpath(genpath('/home/ljp/Science/Hippolyte/multiSensorint'))
            
            % Initialize some values:
            app.nneu1 = 1;
            app.nneu2 = 1;
            app.arotate = 0;
            app.azoom = 0;
            app.aview = [-45, 45];
            app.alim = [0.496, 1.122, 0.276];
            app.mrksize = 5;
            app.cmrksize = 30;
            app.ptool = 2;
            app.whichset = 1;
            app.gauss = 0.01;
            app.search1 = '';
            app.search2 = '';
            app.mypoint = [0, 0, 0];
            app.choicex1 = 1;
            app.choicex2 = 1;
            
            % Plotting parameters:
            app.UIAxes.DataAspectRatio = [1, 1, 1];
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.ZGrid = 'on';
            title(app.UIAxes, 'Figure for multi modal analysis')
            xlabel(app.UIAxes, 'x-axis', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'y-axis', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'z-axis', 'Interpreter', 'latex')
            app.UIAxes.XLim = [0, app.alim(1)];
            app.UIAxes.YLim = [0, app.alim(2)];
            app.UIAxes.ZLim = [0, app.alim(3)];
            view(app.UIAxes, app.aview)
            app.UIAxes2.DataAspectRatio = [1, 1, 1];
            app.UIAxes2.XGrid = 'on';
            app.UIAxes2.YGrid = 'on';
            title(app.UIAxes2, 'X-Z plan point selection')
            xlabel(app.UIAxes2, ' ', 'Interpreter', 'latex')
            ylabel(app.UIAxes2, ' ', 'Interpreter', 'latex')
            app.UIAxes2.XLim = [0, app.alim(1)];
            app.UIAxes2.YLim = [0, app.alim(3)];
            set(app.UIAxes2, 'XTickLabel', [])
            set(app.UIAxes2, 'YTickLabel', [])
            
            % Load MaskDatabase and build landmarks:
            load('zoutlines005.mat', 'zoutlines005');
            app.zoutlines = zoutlines005;
            app.OutplotS = [];
            app.OutplotC = {};
            app.regions = [];
            
            % Load brain regions names:
            load('MaskDatabaseNames.mat', 'MaskDatabaseNames');
            app.PlotthefollowingbrainzonesListBox.Items = {};
            for i = 1:length(MaskDatabaseNames)
                labtemp = num2str(i);
                if length(labtemp) == 1
                    labtemp = strcat('00', labtemp);
                elseif length(labtemp) == 2
                    labtemp = strcat('0', labtemp);
                end
                labtemp = strcat(labtemp, ':', MaskDatabaseNames{i});
                app.PlotthefollowingbrainzonesListBox.Items = [app.PlotthefollowingbrainzonesListBox.Items, labtemp];
            end
            app.ListBox.Items = {};
            
            % Load ind2labels in order to get labels in automaticPlot3:
            load('ind2labels.mat', 'ind2labels');
            app.ind2labels = ind2labels;
        end

        % Button pushed function: LoadZBraingridobjectButton
        function LoadZBraingridobjectButtonPushed(app, event)
            [file, path] = uigetfile;
            if ~endsWith(file, ".mat")
                msgbox('Imported data should be a .mat file.')
            elseif file ~= 0
                % Rebuild path:
                filepath = fullfile(path, file);
                % Get class:
                temp = load(char(filepath));
                field_temp = fields(temp);
                temp = temp.(field_temp{1});
                % Class should be ZBraingrid
                if class(temp) == "ZBraingrid"
                    % Private properties:
                    app.zgrid = temp;
                    app.zgridpath = filepath;
                    app.TextArea.Value = {filepath};
                    % Updating comments and keywords:
                    zgridlen = length(temp.comments);
                    unique_key = "";
                    ListofcommentsDropDown_temp = cell(1, zgridlen);
                    for i = 1:zgridlen
                        unique_key = unique_key + temp.comments(i);
                        if i ~= zgridlen
                            unique_key = unique_key + " + ";
                        end
                        ListofcommentsDropDown_temp{i} = char(temp.comments(i));
                    end
                    app.ListofcommentsDropDown.Items = ListofcommentsDropDown_temp;
                    unique_key = split(unique_key, " + ");
                    unique_key = unique(unique_key);
                    ListofkeywordsDropDown_temp = cell(1, length(unique_key));
                    for i = 1:length(unique_key)
                        ListofkeywordsDropDown_temp{i} = char(unique_key(i));
                    end
                    app.ListofkeywordsDropDown.Items = ListofkeywordsDropDown_temp;
                    msgbox('ZBraingrid successfully loaded!')
                else
                    msgbox('Data to load should be a ZBraingrid object.')
                end
            end
            
%             % Temporary script to get index of 5% most responsive neurons for each stimulus:
%             maxilabs = ["sine", "step", "auditory", "hot", "cold"];
%             bestneurons_persubset = cell(length(maxilabs), 2);
%             for i = 1:length(maxilabs)
%                 subset_temp = subset(app.zgrid, maxilabs(i));
%                 subset_temp = flatten(subset_temp);
%                 [~, tempsort] = sort(subset_temp.Zcorrel, 'descend');
%                 tempsort = tempsort(1:round(length(tempsort)/20));
%                 bestneurons_persubset{i, 1} = maxilabs(i);
%                 bestneurons_persubset{i, 2} = subset_temp.Zindex(tempsort);
%             end
%             pathcreated_bnps = fullfile('/home/ljp/Science/Hippolyte', 'bestneurons_persubset.mat');
%             save(pathcreated_bnps, 'bestneurons_persubset')
        end

        % Value changed function: 
        % Keywordsfordataset1usecommastoseparateEditField
        function Keywordsfordataset1usecommastoseparateEditFieldValueChanged(app, event)
            value = app.Keywordsfordataset1usecommastoseparateEditField.Value;
            % Spliting to find keywords:
            value = split(value, [" , ", " ,", ", ", ","]);
            % Looping to reduce ZBraingrid object:
            app.zset1 = app.zgrid;     
            app.zindex1 = 1:length(app.zset1);
            try
                for i = 1:length(value)
                    % Define dataset:
                    [app.zset1, keepsub] = subset(app.zset1, value(i));  
                    app.zindex1 = app.zindex1(keepsub);
                    % Adapt sliders:
                    obj1 = flatten(app.zset1);
                    disp([i, 0])
                    % Adapt number of neurons slider:
                    app.Dataset1Slider.Limits = [log10(1), log10(length(obj1.Zindex))];
                    app.nneu1 = min([app.nneu1, length(obj1.Zindex)]); %floor(length(obj1.Zindex) / 1000);
                    app.Dataset1Slider.Value = log10(app.nneu1);
                    % Adapt absolut regressor value slider:
                    app.zcobj1 = sort(abs(obj1.Zcorrel), 'descend');
                    app.Dataset1Slider_2.Limits = [0, app.zcobj1(1)];
                    app.maval1 = app.zcobj1(app.nneu1); 
                    app.Dataset1Slider_2.Value = app.maval1; 
                end
                % Message:
                msgbox('Dataset 1 successfully loaded!')
                % Save search:
                app.search1 = char(value);
            catch
                msgbox('Subset to get is empty.')
                % Replacing value:
                app.Keywordsfordataset1usecommastoseparateEditField.Value = app.search1;
                app.search1 = char(value);
            end
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: 
        % Keywordsfordataset2usecommastoseparateEditField
        function Keywordsfordataset2usecommastoseparateEditFieldValueChanged(app, event)
            value = app.Keywordsfordataset2usecommastoseparateEditField.Value;
            % Spliting to find keywords:
            value = split(value, [" , ", " ,", ", ", ","]);
            % Looping to reduce ZBraingrid object:
            app.zset2 = app.zgrid;
            app.zindex2 = 1:length(app.zset2);
            try
                for i = 1:length(value)
                    % Define dataset:
                    [app.zset2, keepsub] = subset(app.zset2, value(i));  
                    app.zindex2 = app.zindex2(keepsub);
                    % Adapt sliders:
                    obj2 = flatten(app.zset2);
                    % Adapt number of neurons slider:
                    app.Dataset2Slider.Limits = [log10(1), log10(length(obj2.Zindex))];
                    app.nneu2 = min([app.nneu2, length(obj2.Zindex)]); %floor(length(obj2.Zindex) / 1000);
                    app.Dataset2Slider.Value = log10(app.nneu2);
                    % Adapt absolut regressor value slider:
                    app.zcobj2 = sort(abs(obj2.Zcorrel), 'descend');
                    app.Dataset2Slider_2.Limits = [0, app.zcobj2(1)];
                    app.maval2 = app.zcobj2(app.nneu2); 
                    app.Dataset2Slider_2.Value = app.maval2; 
                end
                % Message:
                msgbox('Dataset 2 successfully loaded!') 
                % Save search:
                app.search2 = char(value);  
            catch
                msgbox('Subset to get is empty.')
                % Replacing value:
                app.Keywordsfordataset2usecommastoseparateEditField.Value = app.search2;
            end    
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: 
        % PleasechoserepresentationoptionListBox
        function PleasechoserepresentationoptionListBoxValueChanged(app, event)
            value = app.PleasechoserepresentationoptionListBox.Value;
            % Replace app.ptool with selected option:
            possible_values = app.PleasechoserepresentationoptionListBox.Items;
            for i = 1:length(possible_values)
                if value == string(app.PleasechoserepresentationoptionListBox.Items{i})
                    app.ptool = i;
                    break
                end
            end
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: Dataset1Slider
        function Dataset1SliderValueChanged(app, event)
            value = app.Dataset1Slider.Value;
            % Change number of neurons and absolute regressor value:
            app.nneu1 = round(10^value);
            app.maval1 = app.zcobj1(app.nneu1);
            app.Dataset1Slider_2.Value = app.maval1;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: Dataset2Slider
        function Dataset2SliderValueChanged(app, event)
            value = app.Dataset2Slider.Value;
            % Change number of neurons and absolute regressor value:
            app.nneu2 = round(10^value);
            app.maval2 = app.zcobj2(app.nneu2);      
            app.Dataset2Slider_2.Value = app.maval2;   
            % Plot whatever needs to be plot:
            plotWrapper(app)   
        end

        % Value changed function: Dataset1Slider_2
        function Dataset1Slider_2ValueChanged(app, event)
            value = app.Dataset1Slider_2.Value;
            % Change number of neurons and absolute regressor value:
            app.maval1 = value;
            app.nneu1 = sum(app.zcobj1 >= app.maval1);
            if app.nneu1 == 0
                app.nneu1 = 1;
            end
            app.Dataset1Slider.Value = log10(app.nneu1);
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: Dataset2Slider_2
        function Dataset2Slider_2ValueChanged(app, event)
            value = app.Dataset2Slider_2.Value;
            % Change number of neurons and absolute regressor value:
            app.maval2 = value;
            app.nneu2 = sum(app.zcobj2 >= app.maval2);
            if app.nneu2 == 0
                app.nneu2 = 1;
            end
            app.Dataset2Slider.Value = log10(app.nneu2);  
            % Plot whatever needs to be plot:
            plotWrapper(app)          
        end

        % Value changed function: MarkersizeSlider
        function MarkersizeSliderValueChanged(app, event)
            value = app.MarkersizeSlider.Value;
            % Change marker size:
            if value == 0
                value = NaN;
            end
            app.mrksize = value;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Selection changed function: DensitytoplotforButtonGroup
        function DensitytoplotforButtonGroupSelectionChanged(app, event)
            selectedButton = app.DensitytoplotforButtonGroup.SelectedObject;
            % Change whichset property if needed:
            if app.whichset == 1 && selectedButton.Text == "Dataset 2"
                if ~isempty(app.zset2)
                    app.whichset = 2;
                else
                    msgbox('No subset to display.')
                    app.Dataset1Button.Value = true;
                end
            elseif app.whichset == 2 && selectedButton.Text == "Dataset 1"
                if ~isempty(app.zset1)
                    app.whichset = 1;
                else
                    msgbox('No subset to display.')
                    app.Dataset2Button.Value = true;
                end
            end
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: GaussianizeSlider
        function GaussianizeSliderValueChanged(app, event)
            value = app.GaussianizeSlider.Value;
            % Change gaussianity:
            if value == 0
                app.gauss = 0.001;
            else
                app.gauss = value;
            end
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Callback function
        function Isovalue1SliderValueChanged(app, event)
            value = app.Isovalue1Slider.Value;
            % Change isovalue 1:
            app.isoval1 = value;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Callback function
        function Isovalue2SliderValueChanged(app, event)
            value = app.Isovalue2Slider.Value;
            % Change isovalue 2:
            app.isoval2 = value;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Callback function
        function Isovalue3SliderValueChanged(app, event)
            value = app.Isovalue3Slider.Value;
            % Change isovalue 3:
            app.isoval3 = value;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: Quantileforisovalue1EditField
        function Quantileforisovalue1EditFieldValueChanged(app, event)
            value = app.Quantileforisovalue1EditField.Value;
            % Check value is between 0 and one:
            if value < 0 || value > 1
                msgbox('Please enter quantile between 0 and 1.')
                app.Quantileforisovalue1EditField.Value = 0;
            else
                % Else changing isovalue:
                if app.Dataset1Button.Value == true
                    app.isoval1 = quantile(app.zset1.Zcorrel, value);
                elseif app.Dataset2Button.Value == true
                    app.isoval1 = quantile(app.zset2.Zcorrel, value);
                end
                app.Isovalue1EditField.Value = app.isoval1;
            end
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: Quantileforisovalue2EditField
        function Quantileforisovalue2EditFieldValueChanged(app, event)
            value = app.Quantileforisovalue2EditField.Value;
            % Check value is between 0 and one:
            if value < 0 || value > 1
                msgbox('Please enter quantile between 0 and 1.')
                app.Quantileforisovalue2EditField.Value = 0;
            else
                % Else changing isovalue:
                if app.Dataset1Button.Value == true
                    app.isoval2 = quantile(app.zset1.Zcorrel, value);
                elseif app.Dataset2Button.Value == true
                    app.isoval2 = quantile(app.zset2.Zcorrel, value);
                end
                app.Isovalue2EditField.Value = app.isoval2;
            end          
            % Plot whatever needs to be plot:
            plotWrapper(app)  
        end

        % Value changed function: Quantileforisovalue3EditField
        function Quantileforisovalue3EditFieldValueChanged(app, event)
            value = app.Quantileforisovalue3EditField.Value;
            % Check value is between 0 and one:
            if value < 0 || value > 1
                msgbox('Please enter quantile between 0 and 1.')
                app.Quantileforisovalue3EditField.Value = 0;
            else
                % Else changing isovalue:
                if app.Dataset1Button.Value == true
                    app.isoval3 = quantile(app.zset1.Zcorrel, value);
                elseif app.Dataset2Button.Value == true
                    app.isoval3 = quantile(app.zset2.Zcorrel, value);
                end
                app.Isovalue3EditField.Value = app.isoval3;
            end
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Changing value for arotate and azoom:
            app.arotate = 1 * (app.arotate == 0);
            app.azoom = 0;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Changing value for arotate and azoom:
            app.arotate = 0;
            app.azoom = 1 * (app.azoom == 0);
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: ReinitializeButton
        function ReinitializeButtonPushed(app, event)
            % Reinitialize properties:
            app.aview = [-45, 45];
            view(app.UIAxes, app.aview)
            app.arotate = 0;
            app.azoom = 0;
            % Plot whatever needs to be plot:
            plotWrapper(app) 
            % Zoom reset:
            app.UIAxes.XLim = [0, app.alim(1)];
            app.UIAxes.YLim = [0, app.alim(2)];
            app.UIAxes.ZLim = [0, app.alim(3)];
        end

        % Button pushed function: XYButton
        function XYButtonPushed(app, event)
            % Saving view:
            app.aview = [-90, 90];
            view(app.UIAxes, app.aview)
            % Changing value for arotate and azoom:
            app.arotate = 0;
            app.azoom = 0;
            % Plot whatever needs to be plot:
            plotWrapper(app) 
        end

        % Button pushed function: XZButton
        function XZButtonPushed(app, event)
            % Saving view:
            app.aview = [180, 0];
            view(app.UIAxes, app.aview)
            % Changing value for arotate and azoom:
            app.arotate = 0;
            app.azoom = 0;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: YZButton
        function YZButtonPushed(app, event)
            % Saving view:
            app.aview = [-90, 0];
            view(app.UIAxes, app.aview)
            % Changing value for arotate and azoom:
            app.arotate = 0;
            app.azoom = 0;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: PlotscatteredoutlinesofzonesCheckBox
        function PlotscatteredoutlinesofzonesCheckBoxValueChanged(app, event)
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: PlotXYcontoursofzonesCheckBox
        function PlotXYcontoursofzonesCheckBoxValueChanged(app, event)
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: ChangecolorsetButton
        function ChangecolorsetButtonPushed(app, event)
            app.acolor = rand(size(app.acolor));
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: PlotthefollowingbrainzonesListBox
        function PlotthefollowingbrainzonesListBoxValueChanged(app, event)
            value = app.PlotthefollowingbrainzonesListBox.Value;
            app.regions = [];
            app.ListBox.Items = {};
            for i = 1:length(value)
                app.regions = cat(2, app.regions, str2double(value{i}(1:3)));
                app.ListBox.Items = [app.ListBox.Items, value{i}];
            end
            
            % Find new Outplots:
            newRegions(app)
            
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: PlotwholebrainButton
        function PlotwholebrainButtonPushed(app, event)
            % Enabling or not brain zones selection and replacing app.regions:
            if app.PlotthefollowingbrainzonesListBox.Enable == "off"
                app.PlotthefollowingbrainzonesListBox.Enable = 'on';
                app.regions = app.regions(1:end-5);
            else
                app.PlotthefollowingbrainzonesListBox.Enable = 'off';
                app.regions = cat(2, app.regions, [1, 94, 114, 260, 275]);
            end
            
            % Find new Outplots:
            newRegions(app)
            
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: PlotYZcontoursofzonesCheckBox
        function PlotYZcontoursofzonesCheckBoxValueChanged(app, event)
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: PlotXZcontoursofzonesCheckBox
        function PlotXZcontoursofzonesCheckBoxValueChanged(app, event)
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: SamecolorButton
        function SamecolorButtonPushed(app, event)
            app.acolor = [0, 0, 0] .* ones(size(app.acolor));
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: XselectionSlider
        function XselectionSliderValueChanged(app, event)
            value = app.XselectionSlider.Value;
            if ~isempty(app.zgrid)
                % Adapting value to grid:
                tgrid = (app.zgrid.xgrid(2:end) + app.zgrid.xgrid(1:end-1)) / 2;
                [~, indmin] = min((tgrid - value).^2);
                value = tgrid(indmin);
                % Changing mypoint value:
                app.mypoint(1) = value;
                % Plot whatever needs to be plot:
                plotWrapper(app)
            else
                app.XselectionSlider.Value = 0;
            end
        end

        % Value changed function: ZselectionSlider
        function ZselectionSliderValueChanged(app, event)
            value = app.ZselectionSlider.Value;
            if ~isempty(app.zgrid)
                % Adapting value to grid:
                tgrid = (app.zgrid.zgrid(2:end) + app.zgrid.zgrid(1:end-1)) / 2;
                [~, indmin] = min((tgrid - value).^2);
                value = tgrid(indmin);
                % Changing mypoint value:
                app.mypoint(3) = value;
                % Plot whatever needs to be plot:
                plotWrapper(app)
            else
                app.ZselectionSlider.Value = 0;
            end
        end

        % Value changed function: YselectionSlider
        function YselectionSliderValueChanged(app, event)
            value = app.YselectionSlider.Value;
            if ~isempty(app.zgrid)
                % Adapting value to grid:
                tgrid = (app.zgrid.ygrid(2:end) + app.zgrid.ygrid(1:end-1)) / 2;
                [~, indmin] = min((tgrid - value).^2);
                value = tgrid(indmin);
                % Changing mypoint value:
                app.mypoint(2) = value;
                % Plot whatever needs to be plot:
                plotWrapper(app)
            else
                app.YselectionSlider.Value = 0;
            end
        end

        % Value changed function: CommonpointsmarkersizeSlider
        function CommonpointsmarkersizeSliderValueChanged(app, event)
            value = app.CommonpointsmarkersizeSlider.Value;
            % Change marker size:
            if value == 0
                value = NaN;
            end
            app.cmrksize = value;
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: Examplefromset1DropDown
        function Examplefromset1DropDownValueChanged(app, event)
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: Examplefromset2DropDown
        function Examplefromset2DropDownValueChanged(app, event)
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: StimulusDropDown
        function StimulusDropDownValueChanged(app, event)
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Value changed function: StimulusDropDown_2
        function StimulusDropDown_2ValueChanged(app, event)
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: YupButton
        function YupButtonPushed(app, event)
            app.mypoint(2) = app.mypoint(2) + 0.005;
            app.YselectionSlider.Value = app.mypoint(2);
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: YdownButton
        function YdownButtonPushed(app, event)
            app.mypoint(2) = app.mypoint(2) - 0.005;
            app.YselectionSlider.Value = app.mypoint(2);
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: XupButton
        function XupButtonPushed(app, event)
            app.mypoint(1) = app.mypoint(1) + 0.005;
            app.XselectionSlider.Value = app.mypoint(1);
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: XdownButton
        function XdownButtonPushed(app, event)
            app.mypoint(1) = app.mypoint(1) - 0.005;
            app.XselectionSlider.Value = app.mypoint(1);
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: ZupButton
        function ZupButtonPushed(app, event)
            app.mypoint(3) = app.mypoint(3) + 0.005;
            app.ZselectionSlider.Value = app.mypoint(3);
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: ZdownButton
        function ZdownButtonPushed(app, event)
            app.mypoint(3) = app.mypoint(3) - 0.005;
            app.ZselectionSlider.Value = app.mypoint(3);
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end

        % Button pushed function: 
        % SaveindexofclosestpointsforeachsetinworkspaceButton
        function SaveindexofclosestpointsforeachsetinworkspaceButtonPushed(app, event)
            saveClosestPoints(app);
        end

        % Button pushed function: SaveindexofplottedneuronsButton
        function SaveindexofplottedneuronsButtonPushed(app, event)
            saveActiveNeurons(app);
        end

        % Button pushed function: 
        % PlotaveragedresponsetothesestimuliwithinradiusButton
        function PlotaveragedresponsetothesestimuliwithinradiusButtonPushed(app, event)
             plotAveragedResponse(app);
        end

        % Value changed function: PlotonlycommongridpointsCheckBox
        function PlotonlycommongridpointsCheckBoxValueChanged(app, event)
            % Plot whatever needs to be plot:
            plotWrapper(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ComparetwosetstoolsTab = uitab(app.TabGroup);
            app.ComparetwosetstoolsTab.Title = 'Compare two sets - tools';
            app.Dataset1SliderLabel = uilabel(app.ComparetwosetstoolsTab);
            app.Dataset1SliderLabel.Text = 'Dataset 1';
            app.Dataset1Slider = uislider(app.ComparetwosetstoolsTab);
            app.Dataset1Slider.Limits = [0 1];
            app.Dataset1Slider.ValueChangedFcn = createCallbackFcn(app, @Dataset1SliderValueChanged, true);
            app.Dataset2SliderLabel = uilabel(app.ComparetwosetstoolsTab);
            app.Dataset2SliderLabel.Text = 'Dataset 2';
            app.Dataset2Slider = uislider(app.ComparetwosetstoolsTab);
            app.Dataset2Slider.Limits = [0 1];
            app.Dataset2Slider.ValueChangedFcn = createCallbackFcn(app, @Dataset2SliderValueChanged, true);
            app.Dataset1Slider_2Label = uilabel(app.ComparetwosetstoolsTab);
            app.Dataset1Slider_2Label.Text = 'Dataset 1';
            app.Dataset1Slider_2 = uislider(app.ComparetwosetstoolsTab);
            app.Dataset1Slider_2.Limits = [0 1];
            app.Dataset1Slider_2.ValueChangedFcn = createCallbackFcn(app, @Dataset1Slider_2ValueChanged, true);
            app.Dataset2Slider_2Label = uilabel(app.ComparetwosetstoolsTab);
            app.Dataset2Slider_2Label.Text = 'Dataset2';
            app.Dataset2Slider_2 = uislider(app.ComparetwosetstoolsTab);
            app.Dataset2Slider_2.Limits = [0 1];
            app.Dataset2Slider_2.ValueChangedFcn = createCallbackFcn(app, @Dataset2Slider_2ValueChanged, true);
            app.MarkersizeSliderLabel = uilabel(app.ComparetwosetstoolsTab);
            app.MarkersizeSliderLabel.Text = 'Markersize';
            app.MarkersizeSlider = uislider(app.ComparetwosetstoolsTab);
            app.MarkersizeSlider.Limits = [0 15];
            app.MarkersizeSlider.ValueChangedFcn = createCallbackFcn(app, @MarkersizeSliderValueChanged, true);
            app.MarkersizeSlider.Value = 5;
            app.NumberofneuronsLogarithmicscaleLabel = uilabel(app.ComparetwosetstoolsTab);
            app.NumberofneuronsLogarithmicscaleLabel.Text = {'Number of neurons'; '(Logarithmic scale)'};
            app.AbsolutevalueofregressorLabel = uilabel(app.ComparetwosetstoolsTab);
            app.AbsolutevalueofregressorLabel.Text = 'Absolute value of regressor';
            app.CommonpointsmarkersizeSliderLabel = uilabel(app.ComparetwosetstoolsTab);
            app.CommonpointsmarkersizeSliderLabel.Text = {'Common points'; 'markersize'};
            app.CommonpointsmarkersizeSlider = uislider(app.ComparetwosetstoolsTab);
            app.CommonpointsmarkersizeSlider.Limits = [0 40];
            app.CommonpointsmarkersizeSlider.ValueChangedFcn = createCallbackFcn(app, @CommonpointsmarkersizeSliderValueChanged, true);
            app.CommonpointsmarkersizeSlider.Value = 20;
            app.CheckdensitiesofbothsetstoolsTab = uitab(app.TabGroup);
            app.CheckdensitiesofbothsetstoolsTab.Title = 'Check densities of both sets - tools';
            app.DensitytoplotforButtonGroup = uibuttongroup(app.CheckdensitiesofbothsetstoolsTab);
            app.DensitytoplotforButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DensitytoplotforButtonGroupSelectionChanged, true);
            app.DensitytoplotforButtonGroup.Title = 'Density to plot for:';
            app.Dataset1Button = uiradiobutton(app.DensitytoplotforButtonGroup);
            app.Dataset1Button.Text = 'Dataset 1';
            app.Dataset1Button.Value = true;
            app.Dataset2Button = uiradiobutton(app.DensitytoplotforButtonGroup);
            app.Dataset2Button.Text = 'Dataset 2';
            app.GaussianizeSliderLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.GaussianizeSliderLabel.Text = 'Gaussianize';
            app.GaussianizeSlider = uislider(app.CheckdensitiesofbothsetstoolsTab);
            app.GaussianizeSlider.Limits = [0 0.02];
            app.GaussianizeSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianizeSliderValueChanged, true);
            app.GaussianizeSlider.Value = 0.01;
            app.Quantileforisovalue1EditFieldLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Quantileforisovalue1EditFieldLabel.Text = 'Quantile for isovalue 1';
            app.Quantileforisovalue1EditField = uieditfield(app.CheckdensitiesofbothsetstoolsTab, 'numeric');
            app.Quantileforisovalue1EditField.ValueChangedFcn = createCallbackFcn(app, @Quantileforisovalue1EditFieldValueChanged, true);
            app.Quantileforisovalue2EditFieldLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Quantileforisovalue2EditFieldLabel.Text = 'Quantile for isovalue 2';
            app.Quantileforisovalue2EditField = uieditfield(app.CheckdensitiesofbothsetstoolsTab, 'numeric');
            app.Quantileforisovalue2EditField.ValueChangedFcn = createCallbackFcn(app, @Quantileforisovalue2EditFieldValueChanged, true);
            app.Quantileforisovalue3EditFieldLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Quantileforisovalue3EditFieldLabel.Text = 'Quantile for isovalue 3';
            app.Quantileforisovalue3EditField = uieditfield(app.CheckdensitiesofbothsetstoolsTab, 'numeric');
            app.Quantileforisovalue3EditField.ValueChangedFcn = createCallbackFcn(app, @Quantileforisovalue3EditFieldValueChanged, true);
            app.Isovalue1EditFieldLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Isovalue1EditFieldLabel.Enable = 'off';
            app.Isovalue1EditFieldLabel.Text = 'Isovalue 1';
            app.Isovalue1EditField = uieditfield(app.CheckdensitiesofbothsetstoolsTab, 'numeric');
            app.Isovalue1EditField.Enable = 'off';
            app.Isovalue2EditFieldLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Isovalue2EditFieldLabel.Enable = 'off';
            app.Isovalue2EditFieldLabel.Text = 'Isovalue 2';
            app.Isovalue2EditField = uieditfield(app.CheckdensitiesofbothsetstoolsTab, 'numeric');
            app.Isovalue2EditField.Enable = 'off';
            app.Isovalue3EditFieldLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Isovalue3EditFieldLabel.Enable = 'off';
            app.Isovalue3EditFieldLabel.Text = 'Isovalue 3';
            app.Isovalue3EditField = uieditfield(app.CheckdensitiesofbothsetstoolsTab, 'numeric');
            app.Isovalue3EditField.Enable = 'off';
            app.DFFregressorplottoolsTab = uitab(app.TabGroup);
            app.DFFregressorplottoolsTab.Title = 'DFF & regressor plot - tools';
            app.UIAxes2 = uiaxes(app.DFFregressorplottoolsTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.XselectionSliderLabel = uilabel(app.DFFregressorplottoolsTab);
            app.XselectionSliderLabel.Text = 'X-selection';
            app.XselectionSlider = uislider(app.DFFregressorplottoolsTab);
            app.XselectionSlider.Limits = [0 0.496];
            app.XselectionSlider.ValueChangedFcn = createCallbackFcn(app, @XselectionSliderValueChanged, true);
            app.ZselectionSliderLabel = uilabel(app.DFFregressorplottoolsTab);
            app.ZselectionSliderLabel.Text = 'Z-selection';
            app.ZselectionSlider = uislider(app.DFFregressorplottoolsTab);
            app.ZselectionSlider.Limits = [0 0.276];
            app.ZselectionSlider.ValueChangedFcn = createCallbackFcn(app, @ZselectionSliderValueChanged, true);
            app.YselectionSliderLabel = uilabel(app.DFFregressorplottoolsTab);
            app.YselectionSliderLabel.Text = 'Y-selection';
            app.YselectionSlider = uislider(app.DFFregressorplottoolsTab);
            app.YselectionSlider.Limits = [0 1.122];
            app.YselectionSlider.ValueChangedFcn = createCallbackFcn(app, @YselectionSliderValueChanged, true);
            app.UIAxes3 = uiaxes(app.DFFregressorplottoolsTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.DFFregressorplottoolsTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.Examplefromset1DropDownLabel = uilabel(app.DFFregressorplottoolsTab);
            app.Examplefromset1DropDownLabel.Text = 'Example from set 1';
            app.Examplefromset1DropDown = uidropdown(app.DFFregressorplottoolsTab);
            app.Examplefromset1DropDown.Items = {'No set loaded'};
            app.Examplefromset1DropDown.ValueChangedFcn = createCallbackFcn(app, @Examplefromset1DropDownValueChanged, true);
            app.Examplefromset1DropDown.Value = 'No set loaded';
            app.Examplefromset2DropDownLabel = uilabel(app.DFFregressorplottoolsTab);
            app.Examplefromset2DropDownLabel.Text = 'Example from set 2';
            app.Examplefromset2DropDown = uidropdown(app.DFFregressorplottoolsTab);
            app.Examplefromset2DropDown.Items = {'No set loaded'};
            app.Examplefromset2DropDown.ValueChangedFcn = createCallbackFcn(app, @Examplefromset2DropDownValueChanged, true);
            app.Examplefromset2DropDown.Value = 'No set loaded';
            app.LabelsListBox = uilistbox(app.DFFregressorplottoolsTab);
            app.LabelsListBox.Items = {'No region'};
            app.LabelsListBox.Value = 'No region';
            app.LabelsListBox_2 = uilistbox(app.DFFregressorplottoolsTab);
            app.LabelsListBox_2.Items = {'No region'};
            app.LabelsListBox_2.Value = 'No region';
            app.StimulusDropDownLabel = uilabel(app.DFFregressorplottoolsTab);
            app.StimulusDropDownLabel.Text = 'Stimulus';
            app.StimulusDropDown = uidropdown(app.DFFregressorplottoolsTab);
            app.StimulusDropDown.Items = {'No set loaded'};
            app.StimulusDropDown.ValueChangedFcn = createCallbackFcn(app, @StimulusDropDownValueChanged, true);
            app.StimulusDropDown.Value = 'No set loaded';
            app.StimulusDropDown_2Label = uilabel(app.DFFregressorplottoolsTab);
            app.StimulusDropDown_2Label.Text = 'Stimulus';
            app.StimulusDropDown_2 = uidropdown(app.DFFregressorplottoolsTab);
            app.StimulusDropDown_2.Items = {'No set loaded'};
            app.StimulusDropDown_2.ValueChangedFcn = createCallbackFcn(app, @StimulusDropDown_2ValueChanged, true);
            app.StimulusDropDown_2.Value = 'No set loaded';
            app.YupButton = uibutton(app.DFFregressorplottoolsTab, 'push');
            app.YupButton.ButtonPushedFcn = createCallbackFcn(app, @YupButtonPushed, true);
            app.YupButton.Text = 'Y-up';
            app.YdownButton = uibutton(app.DFFregressorplottoolsTab, 'push');
            app.YdownButton.ButtonPushedFcn = createCallbackFcn(app, @YdownButtonPushed, true);
            app.YdownButton.Text = 'Y-down';
            app.XdownButton = uibutton(app.DFFregressorplottoolsTab, 'push');
            app.XdownButton.ButtonPushedFcn = createCallbackFcn(app, @XdownButtonPushed, true);
            app.XdownButton.Text = 'X-down';
            app.XupButton = uibutton(app.DFFregressorplottoolsTab, 'push');
            app.XupButton.ButtonPushedFcn = createCallbackFcn(app, @XupButtonPushed, true);
            app.XupButton.Text = 'X-up';
            app.ZupButton = uibutton(app.DFFregressorplottoolsTab, 'push');
            app.ZupButton.ButtonPushedFcn = createCallbackFcn(app, @ZupButtonPushed, true);
            app.ZupButton.Text = 'Z-up';
            app.ZdownButton = uibutton(app.DFFregressorplottoolsTab, 'push');
            app.ZdownButton.ButtonPushedFcn = createCallbackFcn(app, @ZdownButtonPushed, true);
            app.ZdownButton.Text = 'Z-down';
            app.PlotbrainzonesTab = uitab(app.TabGroup);
            app.PlotbrainzonesTab.Title = 'Plot brain zones';
            app.PlotscatteredoutlinesofzonesCheckBox = uicheckbox(app.PlotbrainzonesTab);
            app.PlotscatteredoutlinesofzonesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotscatteredoutlinesofzonesCheckBoxValueChanged, true);
            app.PlotscatteredoutlinesofzonesCheckBox.Text = 'Plot scattered outlines of zones';
            app.PlotXYcontoursofzonesCheckBox = uicheckbox(app.PlotbrainzonesTab);
            app.PlotXYcontoursofzonesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotXYcontoursofzonesCheckBoxValueChanged, true);
            app.PlotXYcontoursofzonesCheckBox.Text = 'Plot X-Y contours of zones';
            app.ChangecolorsetButton = uibutton(app.PlotbrainzonesTab, 'push');
            app.ChangecolorsetButton.ButtonPushedFcn = createCallbackFcn(app, @ChangecolorsetButtonPushed, true);
            app.ChangecolorsetButton.Text = 'Change colorset';
            app.PlotthefollowingbrainzonesListBoxLabel = uilabel(app.PlotbrainzonesTab);
            app.PlotthefollowingbrainzonesListBoxLabel.Text = 'Plot the following brain zones';
            app.PlotthefollowingbrainzonesListBox = uilistbox(app.PlotbrainzonesTab);
            app.PlotthefollowingbrainzonesListBox.Items = {'Loading brain zones names'};
            app.PlotthefollowingbrainzonesListBox.Multiselect = 'on';
            app.PlotthefollowingbrainzonesListBox.ValueChangedFcn = createCallbackFcn(app, @PlotthefollowingbrainzonesListBoxValueChanged, true);
            app.PlotthefollowingbrainzonesListBox.Value = {'Loading brain zones names'};
            app.presscontroltopickmultipleLabel = uilabel(app.PlotbrainzonesTab);
            app.presscontroltopickmultipleLabel.Text = '(press control to pick multiple)';
            app.ListBox = uilistbox(app.PlotbrainzonesTab);
            app.ListBox.Items = {'Loading brain zones names'};
            app.ListBox.Enable = 'off';
            app.ListBox.Value = 'Loading brain zones names';
            app.PlotwholebrainButton = uibutton(app.PlotbrainzonesTab, 'push');
            app.PlotwholebrainButton.ButtonPushedFcn = createCallbackFcn(app, @PlotwholebrainButtonPushed, true);
            app.PlotwholebrainButton.Text = 'Plot whole brain';
            app.PlotYZcontoursofzonesCheckBox = uicheckbox(app.PlotbrainzonesTab);
            app.PlotYZcontoursofzonesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotYZcontoursofzonesCheckBoxValueChanged, true);
            app.PlotYZcontoursofzonesCheckBox.Text = 'Plot Y-Z contours of zones';
            app.PlotXZcontoursofzonesCheckBox = uicheckbox(app.PlotbrainzonesTab);
            app.PlotXZcontoursofzonesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotXZcontoursofzonesCheckBoxValueChanged, true);
            app.PlotXZcontoursofzonesCheckBox.Text = 'Plot X-Z contours of zones';
            app.SamecolorButton = uibutton(app.PlotbrainzonesTab, 'push');
            app.SamecolorButton.ButtonPushedFcn = createCallbackFcn(app, @SamecolorButtonPushed, true);
            app.SamecolorButton.Text = 'Same color';
            app.AnalysistoolsTab = uitab(app.TabGroup);
            app.AnalysistoolsTab.Title = 'Analysis tools';
            app.RadiusSliderLabel = uilabel(app.AnalysistoolsTab);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.AnalysistoolsTab);
            app.RadiusSlider.Limits = [0 0.1];
            app.RadiusSlider.MajorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1];
            app.RadiusSlider.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '0.1'};
            app.RadiusSlider.MinorTicks = [];
            app.SaveindexofclosestpointsforeachsetinworkspaceButton = uibutton(app.AnalysistoolsTab, 'push');
            app.SaveindexofclosestpointsforeachsetinworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveindexofclosestpointsforeachsetinworkspaceButtonPushed, true);
            app.SaveindexofclosestpointsforeachsetinworkspaceButton.Text = 'Save index of closest points for each set in workspace';
            app.SaveindexofplottedneuronsButton = uibutton(app.AnalysistoolsTab, 'push');
            app.SaveindexofplottedneuronsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveindexofplottedneuronsButtonPushed, true);
            app.SaveindexofplottedneuronsButton.Text = 'Save index of plotted neurons';
            app.PlotaveragedresponsetothesestimuliwithinradiusButton = uibutton(app.AnalysistoolsTab, 'push');
            app.PlotaveragedresponsetothesestimuliwithinradiusButton.ButtonPushedFcn = createCallbackFcn(app, @PlotaveragedresponsetothesestimuliwithinradiusButtonPushed, true);
            app.PlotaveragedresponsetothesestimuliwithinradiusButton.Text = 'Plot averaged response to these stimuli within radius';
            app.ProgressEditFieldLabel = uilabel(app.AnalysistoolsTab);
            app.ProgressEditFieldLabel.Enable = 'off';
            app.ProgressEditFieldLabel.Text = 'Progress (%)';
            app.ProgressEditField = uieditfield(app.AnalysistoolsTab, 'numeric');
            app.ProgressEditField.Enable = 'off';
            app.LoadZBraingridobjectButton = uibutton(app.UIFigure, 'push');
            app.LoadZBraingridobjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadZBraingridobjectButtonPushed, true);
            app.LoadZBraingridobjectButton.Text = 'Load ZBraingrid object';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'No ZBraingrid data provided yet'};
            app.ListofcommentsDropDownLabel = uilabel(app.UIFigure);
            app.ListofcommentsDropDownLabel.Text = 'List of comments';
            app.ListofcommentsDropDown = uidropdown(app.UIFigure);
            app.ListofcommentsDropDown.Items = {'No Zbraingrid data provided yet'};
            app.ListofcommentsDropDown.Value = 'No Zbraingrid data provided yet';
            app.ListofkeywordsDropDownLabel = uilabel(app.UIFigure);
            app.ListofkeywordsDropDownLabel.Text = 'List of keywords';
            app.ListofkeywordsDropDown = uidropdown(app.UIFigure);
            app.ListofkeywordsDropDown.Items = {'No ZBraingrid data provided yet'};
            app.ListofkeywordsDropDown.Value = 'No ZBraingrid data provided yet';
            app.Keywordsfordataset1usecommastoseparateEditFieldLabel = uilabel(app.UIFigure);
            app.Keywordsfordataset1usecommastoseparateEditFieldLabel.Text = {'Keywords for dataset 1'; '(use commas to separate) '};
            app.Keywordsfordataset1usecommastoseparateEditField = uieditfield(app.UIFigure, 'text');
            app.Keywordsfordataset1usecommastoseparateEditField.ValueChangedFcn = createCallbackFcn(app, @Keywordsfordataset1usecommastoseparateEditFieldValueChanged, true);
            app.Keywordsfordataset2usecommastoseparateEditFieldLabel = uilabel(app.UIFigure);
            app.Keywordsfordataset2usecommastoseparateEditFieldLabel.Text = {'Keywords for dataset 2'; '(use commas to separate)'};
            app.Keywordsfordataset2usecommastoseparateEditField = uieditfield(app.UIFigure, 'text');
            app.Keywordsfordataset2usecommastoseparateEditField.ValueChangedFcn = createCallbackFcn(app, @Keywordsfordataset2usecommastoseparateEditFieldValueChanged, true);
            app.PleasechoserepresentationoptionLabel = uilabel(app.UIFigure);
            app.PleasechoserepresentationoptionLabel.Text = 'Please chose representation option:';
            app.PleasechoserepresentationoptionListBox = uilistbox(app.UIFigure);
            app.PleasechoserepresentationoptionListBox.Items = {'Compare two sets', 'Check densities of both sets', 'DFF & regressor plot'};
            app.PleasechoserepresentationoptionListBox.ValueChangedFcn = createCallbackFcn(app, @PleasechoserepresentationoptionListBoxValueChanged, true);
            app.PleasechoserepresentationoptionListBox.Value = 'Check densities of both sets';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.RotateButton = uibutton(app.ControlsPanel, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.XZButton = uibutton(app.ControlsPanel, 'push');
            app.XZButton.ButtonPushedFcn = createCallbackFcn(app, @XZButtonPushed, true);
            app.XZButton.Text = 'X-Z';
            app.XYButton = uibutton(app.ControlsPanel, 'push');
            app.XYButton.ButtonPushedFcn = createCallbackFcn(app, @XYButtonPushed, true);
            app.XYButton.Text = 'X-Y';
            app.YZButton = uibutton(app.ControlsPanel, 'push');
            app.YZButton.ButtonPushedFcn = createCallbackFcn(app, @YZButtonPushed, true);
            app.YZButton.Text = 'Y-Z';
            app.ZoomButton = uibutton(app.ControlsPanel, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Text = 'Zoom';
            app.ReinitializeButton = uibutton(app.ControlsPanel, 'push');
            app.ReinitializeButton.ButtonPushedFcn = createCallbackFcn(app, @ReinitializeButtonPushed, true);
            app.ReinitializeButton.Text = 'Reinitialize';
            app.PlotonlycommongridpointsCheckBox = uicheckbox(app.UIFigure);
            app.PlotonlycommongridpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotonlycommongridpointsCheckBoxValueChanged, true);
            app.PlotonlycommongridpointsCheckBox.Text = 'Plot only common gridpoints';
        end
    end

    methods (Access = public)

        % Construct app
        function app = visualizeAppReg

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end