classdef visualizeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        stsetSpinnerLabel               matlab.ui.control.Label
        stsetSpinner                    matlab.ui.control.Spinner
        ndsetSpinnerLabel               matlab.ui.control.Label
        ndsetSpinner                    matlab.ui.control.Spinner
        stsetSliderLabel                matlab.ui.control.Label
        stsetSlider                     matlab.ui.control.Slider
        matpathtofileEditFieldLabel     matlab.ui.control.Label
        matpathtofileEditField          matlab.ui.control.EditField
        ndsetSliderLabel                matlab.ui.control.Label
        ndsetSlider                     matlab.ui.control.Slider
        RotateButton                    matlab.ui.control.Button
        ZoomButton                      matlab.ui.control.Button
        ReinitializeButton              matlab.ui.control.Button
        NumberofneuronstodisplayLabel   matlab.ui.control.Label
        CommonpointsmarkersizeSliderLabel  matlab.ui.control.Label
        CommonpointsmarkersizeSlider    matlab.ui.control.Slider
        UncommonpointsmarkersizeSliderLabel  matlab.ui.control.Label
        UncommonpointsmarkersizeSlider  matlab.ui.control.Slider
        ChoiceofpointLabel              matlab.ui.control.Label
        xaxispointSliderLabel           matlab.ui.control.Label
        xaxispointSlider                matlab.ui.control.Slider
        yaxispointSliderLabel           matlab.ui.control.Label
        yaxispointSlider                matlab.ui.control.Slider
        zaxispointSliderLabel           matlab.ui.control.Label
        zaxispointSlider                matlab.ui.control.Slider
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        Examplefromset1DropDownLabel    matlab.ui.control.Label
        Examplefromset1DropDown         matlab.ui.control.DropDown
        Examplefromset2DropDownLabel    matlab.ui.control.Label
        Examplefromset2DropDown         matlab.ui.control.DropDown
        XYButton                        matlab.ui.control.Button
        XZButton                        matlab.ui.control.Button
        YZButton                        matlab.ui.control.Button
        CorrelationTextAreaLabel        matlab.ui.control.Label
        CorrelationTextArea             matlab.ui.control.TextArea
        CorrelationTextArea_2Label      matlab.ui.control.Label
        CorrelationTextArea_2           matlab.ui.control.TextArea
    end


    properties (Access = private)
        zgrid % data loaded at the beginning, should be a cell of ZBraingrids
        set1 % index of first set
        set2 % index of second set
        nneu1 % number of neurons for first set
        nneu2 % number of neurons for second set
        arotate % to rotate figure
        azoom % to zoom in figure
        aview % Rotation of plot
        alim % limits for the axes
        mrksize1 % markersize for common points
        mrksize2 % markersize for non common points
        mypoint % selected point to display
        selpoint1 % closest point to mypoint in set 1
        selpoint2 % closest point to mypoint in set 2
        dexample1 % datasets associated to set1 comprising selpoint1
        dexample2 % datasets associated to set2 comprising selpoint2
        choicex1 % choice in dexample1 to plot for set 1
        choicex2 % choice in dexample2 to plot for set 2
    end

    methods (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function automaticPlot(app)
        % Plots binaryComp.
            
            % Recovers values:
            obj1 = app.zgrid{app.set1};
            obj2 = app.zgrid{app.set2};
            bestneurons = [app.nneu1, app.nneu2];
            % Checking grids:
            if ~isequal(obj1.gridsize(1:3), obj2.gridsize(1:3))
                error('Objects do not have same gridsize.')
            else
                sgrid = obj1.gridsize(1:3);
            end
            
            % Binarization:
            otemp1 = flatten(obj1);
            grid1 = zeros(sgrid);
            [~, ind_correl1] = sort(otemp1.Zcorrel, 'descend');
            ind_correl1 = ind_correl1(1:bestneurons(1));
            grid1(otemp1.Zindex(ind_correl1)) = 1;
            otemp2 = flatten(obj2);
            grid2 = zeros(sgrid);
            [~, ind_correl2] = sort(otemp2.Zcorrel, 'descend');
            ind_correl2 = ind_correl2(1:bestneurons(2));
            grid2(otemp2.Zindex(ind_correl2)) = 2;
            % Summing:
            gridt = grid1 + grid2; find(gridt ~= 0);
            
            % Defining points:
            pt_both = find(gridt == 3);
            pt_o1 = find(gridt == 1);
            pt_o2 = find(gridt == 2);
            % Defining plots:
            [x_both, y_both, z_both] = ind2sub(sgrid, pt_both);
            [x_o1, y_o1, z_o1] = ind2sub(sgrid, pt_o1);
            [x_o2, y_o2, z_o2] = ind2sub(sgrid, pt_o2);
            
            % Using right coordinates:
            xtemp = (obj1.xgrid(2:end)' + obj1.xgrid(1:end-1)') / 2;
            ytemp = (obj1.ygrid(2:end)' + obj1.ygrid(1:end-1)') / 2;
            ztemp = (obj1.zgrid(2:end)' + obj1.zgrid(1:end-1)') / 2;
            compval = @(X) [xtemp(X(:, 1)), ytemp(X(:, 2)), ztemp(X(:, 3))];
            t_both_temp = compval([x_both, y_both, z_both]);
            x_both = t_both_temp(:, 1);
            y_both = t_both_temp(:, 2);
            z_both = t_both_temp(:, 3);
            t_o1_temp = compval([x_o1, y_o1, z_o1]);
            x_o1 = t_o1_temp(:, 1);
            y_o1 = t_o1_temp(:, 2);
            z_o1 = t_o1_temp(:, 3);
            t_o2_temp = compval([x_o2, y_o2, z_o2]);
            x_o2 = t_o2_temp(:, 1);
            y_o2 = t_o2_temp(:, 2);
            z_o2 = t_o2_temp(:, 3);
            
            % Plotting:
            scatter3(app.UIAxes, x_both, y_both, z_both, app.mrksize1, [0, 0, 0], 'filled');
            hold(app.UIAxes, 'on')
            scatter3(app.UIAxes, x_o1, y_o1, z_o1, app.mrksize2, [0, 1, 0], 'filled')
            scatter3(app.UIAxes, x_o2, y_o2, z_o2, app.mrksize2, [1, 0, 1], 'filled')
                % Plotting selected points:
                scatter3(app.UIAxes, app.selpoint1(1), app.selpoint1(2), app.selpoint1(3), 50, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [0, 1, 0])
                scatter3(app.UIAxes, app.selpoint2(1), app.selpoint2(2), app.selpoint2(3), 50, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [1, 0, 1])
                scatter3(app.UIAxes, app.mypoint(1), app.mypoint(2), app.mypoint(3), 50, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [1, 0, 0])
            app.UIAxes.DataAspectRatio = [1, 1, 1];
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.ZGrid = 'on';
            title(app.UIAxes, 'Binary comparison between two objects: common in black, green for 1st, purple for 2nd')
            xlabel(app.UIAxes, 'x-axis', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'y-axis', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'z-axis', 'Interpreter', 'latex')
            view(app.UIAxes, app.aview)
            hold(app.UIAxes, 'off')
            if app.arotate == 1
                rotate3d(app.UIAxes,'on')
                zoom(app.UIAxes,'off')
            elseif app.azoom == 1
                rotate3d(app.UIAxes,'off')
                zoom(app.UIAxes,'on')
            else
                rotate3d(app.UIAxes,'off')
                zoom(app.UIAxes,'off')
            end         
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function closest_points(app)
        % Takes mypoint and computes selpoint1 and selpoint2, closest points in sets 1 and 2 respectively.
            
            % Loading data:
            obj1 = app.zgrid{app.set1};
            obj2 = app.zgrid{app.set2};
            
            % Coordinates function:
            xtemp = (obj1.xgrid(2:end)' + obj1.xgrid(1:end-1)') / 2;
            ytemp = (obj1.ygrid(2:end)' + obj1.ygrid(1:end-1)') / 2;
            ztemp = (obj1.zgrid(2:end)' + obj1.zgrid(1:end-1)') / 2;
            compval = @(X) [xtemp(X(:, 1)), ytemp(X(:, 2)), ztemp(X(:, 3))];
            
            % otemp1 closest point:
            [x1temp, y1temp, z1temp, d1temp] = ind2sub(obj1.gridsize, obj1.Zindex);
            Xobj1 = compval([x1temp, y1temp, z1temp]);
            distot1 = sum((Xobj1 - app.mypoint).^2 , 2);
            [~, indist1] = min(distot1);
            app.selpoint1 = Xobj1(indist1, :);
            % Back to subsets:
            intsel1x = find(app.selpoint1(1) == xtemp);
            intsel1y = find(app.selpoint1(2) == ytemp);
            intsel1z = find(app.selpoint1(3) == ztemp);
            indsel1 = sub2ind(obj1.gridsize, intsel1x, intsel1y, intsel1z);
            app.dexample1 = d1temp(indsel1 == (obj1.Zindex - (d1temp-1).*prod(obj1.gridsize(1:3))));
            % Changing drop down:
            app.Examplefromset1DropDown.Items = {};
            for i = 1:length(app.dexample1)
                app.Examplefromset1DropDown.Items = [app.Examplefromset1DropDown.Items, num2str(app.dexample1(i))];
            end
            app.Examplefromset1DropDown.Value = app.Examplefromset1DropDown.Items{1};
            app.choicex1 = str2double(app.Examplefromset1DropDown.Items{1});
            
            % otemp2 closest point:
            [x2temp, y2temp, z2temp, d2temp] = ind2sub(obj2.gridsize, obj2.Zindex);
            Xobj2 = compval([x2temp, y2temp, z2temp]);
            distot2 = sum((Xobj2 - app.mypoint).^2 , 2);
            [~, indist2] = min(distot2);
            app.selpoint2 = Xobj2(indist2, :);
            % Back to subsets:
            intsel2x = find(app.selpoint2(1) == xtemp);
            intsel2y = find(app.selpoint2(2) == ytemp);
            intsel2z = find(app.selpoint2(3) == ztemp);
            indsel2 = sub2ind(obj2.gridsize, intsel2x, intsel2y, intsel2z);
            app.dexample2 = d2temp(indsel2 == (obj2.Zindex - (d2temp-1).*prod(obj2.gridsize(1:3))));
            % Changing drop down:
            app.Examplefromset2DropDown.Items = {};
            for i = 1:length(app.dexample2)
                app.Examplefromset2DropDown.Items = [app.Examplefromset2DropDown.Items, num2str(app.dexample2(i))];
            end
            app.Examplefromset2DropDown.Value = app.Examplefromset2DropDown.Items{1};
            app.choicex2 = str2double(app.Examplefromset2DropDown.Items{1});
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function plot_DFF(app)
        % Plots averaged DFF for sets 1 and 2, respectively in UIAxes2 and UIAxes3.
        
            % Recovering objects and keeping good sets:
            obj1 = app.zgrid{app.set1};
            obj1 = obj1(app.choicex1);
            obj2 = app.zgrid{app.set2};
            obj2 = obj2(app.choicex2);
            
            % Grid coordinates:
            xtemp = (obj1.xgrid(2:end)' + obj1.xgrid(1:end-1)') / 2;
            ytemp = (obj1.ygrid(2:end)' + obj1.ygrid(1:end-1)') / 2;
            ztemp = (obj1.zgrid(2:end)' + obj1.zgrid(1:end-1)') / 2;
            
            % Subs to indexes and closest points:
            intsel1x = find(app.selpoint1(1) == xtemp);
            intsel1y = find(app.selpoint1(2) == ytemp);
            intsel1z = find(app.selpoint1(3) == ztemp);
            indpt1 = sub2ind(obj1.gridsize, intsel1x, intsel1y, intsel1z);
            intsel2x = find(app.selpoint2(1) == xtemp);
            intsel2y = find(app.selpoint2(2) == ytemp);
            intsel2z = find(app.selpoint2(3) == ztemp);
            indpt2 = sub2ind(obj2.gridsize, intsel2x, intsel2y, intsel2z);
            
            % For set 1:
                % Get index:
                indneu = (indpt1 == obj1.Zindex);
                % Display correlation:
                app.CorrelationTextArea.Value = {num2str(obj1.Zcorrel(indneu))};
                % Recover associated neurons and cleaning:
                zneutemp = obj1.Zneuron(indneu, :);
                zneutemp(zneutemp == 0) = [];
                % Accessing HDF5:
                ptemp = char(obj1.paths(1));
                stim_path = h5readatt(ptemp, '/Metadata', 'Stimulus path');
                dff = h5read(ptemp, '/Data/Brain/Analysis/DFF');
                dff = mean(dff(zneutemp, :), 1);
                stim = h5read(ptemp, stim_path);
                times = h5read(ptemp, '/Data/Brain/Times');
                % Normalizing:
                stim = stim / std(stim) * std(dff);
                % Plotting:
                plot(app.UIAxes2, times, stim)
                hold(app.UIAxes2, 'on')
                plot(app.UIAxes2, times, dff)
                title(app.UIAxes2, 'Stimulus (blue) and DFF(red) against time for set 1')
                xlabel(app.UIAxes2, 'Times [s]', 'Interpreter', 'latex')
                ylabel(app.UIAxes2, 'Stimulus and DFF', 'Interpreter', 'latex')
                app.UIAxes2.XGrid = 'on';
                app.UIAxes2.YGrid = 'on';
                app.UIAxes2.ZGrid = 'on';
                hold(app.UIAxes2, 'off')
            
            % For set 2:
                % Get index:
                indneu = (indpt2 == obj2.Zindex);
                % Display correlation:
                app.CorrelationTextArea_2.Value = {num2str(obj2.Zcorrel(indneu))};
                % Recover associated neurons and cleaning:
                zneutemp = obj2.Zneuron(indneu, :);
                zneutemp(zneutemp == 0) = [];
                % Accessing HDF5:
                ptemp = char(obj2.paths(1));
                stim_path = h5readatt(ptemp, '/Metadata', 'Stimulus path');
                dff = h5read(ptemp, '/Data/Brain/Analysis/DFF');
                dff = mean(dff(zneutemp, :), 1);
                stim = h5read(ptemp, stim_path);
                times = h5read(ptemp, '/Data/Brain/Times');
                % Normalizing:
                stim = stim / std(stim) * std(dff);
                % Plotting:
                plot(app.UIAxes3, times, stim)
                hold(app.UIAxes3, 'on')
                plot(app.UIAxes3, times, dff)
                title(app.UIAxes3, 'Stimulus (blue) and DFF(red) against time for set 2')
                xlabel(app.UIAxes3, 'Times [s]', 'Interpreter', 'latex')
                ylabel(app.UIAxes3, 'Stimulus and DFF', 'Interpreter', 'latex')
                app.UIAxes3.XGrid = 'on';
                app.UIAxes3.YGrid = 'on';
                app.UIAxes3.ZGrid = 'on';
                hold(app.UIAxes3, 'off')
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add path to class:
            addpath(genpath('/home/ljp/Science/Hippolyte/multiSensorint'))
            
            % Initialize properties:
            app.set1 = 1;
            app.set2 = 2;
            app.nneu1 = 500;
            app.nneu2 = 500;
            app.arotate = 0;
            app.azoom = 0;
            app.aview = [-45, 45];
            app.alim = [0.496, 1.122, 0.276];
            app.mrksize1 = 40;
            app.mrksize2 = 3;
            app.mypoint = [0.248, 0.561, 0.138];
            
            % Plotting parameters:
            app.UIAxes.DataAspectRatio = [1, 1, 1];
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.ZGrid = 'on';
            title(app.UIAxes, 'Binary comparison between two objects: common in black, green for 1st, purple for 2nd')
            xlabel(app.UIAxes, 'x-axis', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'y-axis', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'z-axis', 'Interpreter', 'latex')
            app.UIAxes.XLim = [0, app.alim(1)];
            app.UIAxes.YLim = [0, app.alim(2)];
            app.UIAxes.ZLim = [0, app.alim(3)];
            view(app.UIAxes, app.aview)
        end

        % Value changed function: matpathtofileEditField
        function matpathtofileEditFieldValueChanged(app, event)
            value = app.matpathtofileEditField.Value;
            % Loadig data and checking format:
            try
                temp = load(char(value));
                field_temp = fields(temp);
                field_temp = field_temp{1};
                temp = temp.(field_temp);
                keep_temp = 1;
                if ~iscell(temp)
                    msgbox('Loaded data should be a cell.')
                    keep_temp = 0;
                else
                    for i = 1:length(temp)
                        if class(temp{i}) ~= "ZBraingrid"
                            msgbox('Cells should be ZBraingrid objects.')
                            keep_temp = 0;
                        end
                    end
                end
                if keep_temp == 1
                    app.zgrid = temp;
                    % Changing values for set cursor:
                    app.stsetSpinner.Limits = [1 length(app.zgrid)];
                    app.ndsetSpinner.Limits = [1 length(app.zgrid)];
                end
            catch
                msgbox('No existing file named this way.')
            end
                    % Closest points:
                    closest_points(app)
                    % Choicex:
                    app.choicex1 = app.dexample1(1);
                    app.choicex2 = app.dexample2(1);
                    % Plot:
                    automaticPlot(app)
                    % DFF plot:
                    plot_DFF(app)
        end

        % Value changed function: stsetSpinner
        function stsetSpinnerValueChanged(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Change set value:
            value = app.stsetSpinner.Value;
            app.set1 = value;
            % Closest points:
            closest_points(app)
            % Plot:
            automaticPlot(app)
            % DFF plot:
            plot_DFF(app)
        end

        % Value changed function: ndsetSpinner
        function ndsetSpinnerValueChanged(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Change set value:
            value = app.ndsetSpinner.Value;
            app.set2 = value;
            % Closest points:
            closest_points(app)
            % Plot:
            automaticPlot(app)
            % DFF plot:
            plot_DFF(app)
        end

        % Value changed function: stsetSlider
        function stsetSliderValueChanged(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Change number of neurons value:
            value = app.stsetSlider.Value;
            app.nneu1 = value;
            % Plot:
            automaticPlot(app)        
        end

        % Value changed function: ndsetSlider
        function ndsetSliderValueChanged(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Change number of neurons value:
            value = app.ndsetSlider.Value;
            app.nneu2 = value;
            % Plot:
            automaticPlot(app)                
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Changing value for arotate and azoom:
            app.arotate = 1 * (app.arotate == 0);
            app.azoom = 0;
            % Plot:
            automaticPlot(app)   
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Changing value for arotate and azoom:
            app.arotate = 0;
            app.azoom = 1 * (app.azoom == 0);
            % Plot:
            automaticPlot(app)   
        end

        % Button pushed function: ReinitializeButton
        function ReinitializeButtonPushed(app, event)
            % Reinitialize properties:
            app.aview = [-45, 45];
            app.arotate = 0;
            app.azoom = 0;
            % Plot:
            automaticPlot(app)  
            % Zoom reset:
            app.UIAxes.XLim = [0, app.alim(1)];
            app.UIAxes.YLim = [0, app.alim(2)];
            app.UIAxes.ZLim = [0, app.alim(3)];
        end

        % Value changed function: CommonpointsmarkersizeSlider
        function CommonpointsmarkersizeSliderValueChanged(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Change markersize value:
            value = app.CommonpointsmarkersizeSlider.Value;
            if value == 0
                value = 0.5;
            end
            app.mrksize1 = value;
            % Plot:
            automaticPlot(app)  
        end

        % Value changed function: UncommonpointsmarkersizeSlider
        function UncommonpointsmarkersizeSliderValueChanged(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Change markersize value:
            value = app.UncommonpointsmarkersizeSlider.Value;
            if value == 0
                value = 0.5;
            end
            app.mrksize2 = value;
            % Plot:
            automaticPlot(app)              
        end

        % Value changed function: xaxispointSlider
        function xaxispointSliderValueChanged(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Change axis of point of interest:
            value = app.xaxispointSlider.Value;
            app.mypoint(1) = value;
            % Plot:
            closest_points(app)
            automaticPlot(app)
            % DFF plot:
            plot_DFF(app)
        end

        % Value changed function: yaxispointSlider
        function yaxispointSliderValueChanged(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Change axis of point of interest:
            value = app.yaxispointSlider.Value;
            app.mypoint(2) = value;
            % Plot:
            closest_points(app)
            automaticPlot(app)
            % DFF plot:
            plot_DFF(app)
        end

        % Value changed function: zaxispointSlider
        function zaxispointSliderValueChanged(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Change axis of point of interest:
            value = app.zaxispointSlider.Value;
            app.mypoint(3) = value;
            % Plot:
            closest_points(app)
            automaticPlot(app)
            % DFF plot:
            plot_DFF(app)
        end

        % Value changed function: Examplefromset1DropDown
        function Examplefromset1DropDownValueChanged(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Change axis of point of interest:
            value = app.Examplefromset1DropDown.Value;
            app.choicex1 = str2double(value);
            app.Examplefromset1DropDown.Value = value;
            % DFF plot:
            plot_DFF(app)
        end

        % Value changed function: Examplefromset2DropDown
        function Examplefromset2DropDownValueChanged(app, event)
            % Saving view:
            app.aview = get(app.UIAxes, 'View');
            % Change axis of point of interest:
            value = app.Examplefromset2DropDown.Value;
            app.choicex2 = str2double(value);
            app.Examplefromset2DropDown.Value = value;
            % DFF plot:
            plot_DFF(app)
        end

        % Button pushed function: XYButton
        function XYButtonPushed(app, event)
            % Saving view:
            app.aview = [-90, 90];
            % Changing value for arotate and azoom:
            app.arotate = 0;
            app.azoom = 0;
            % Plot:
            automaticPlot(app)  
        end

        % Button pushed function: XZButton
        function XZButtonPushed(app, event)
            % Saving view:
            app.aview = [180, 0];
            % Changing value for arotate and azoom:
            app.arotate = 0;
            app.azoom = 0;
            % Plot:
            automaticPlot(app)  
        end

        % Button pushed function: YZButton
        function YZButtonPushed(app, event)
            % Saving view:
            app.aview = [-90, 0];
            % Changing value for arotate and azoom:
            app.arotate = 0;
            app.azoom = 0;
            % Plot:
            automaticPlot(app)  
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.stsetSpinnerLabel = uilabel(app.UIFigure);
            app.stsetSpinnerLabel.Text = '1st set';
            app.stsetSpinner = uispinner(app.UIFigure);
            app.stsetSpinner.Limits = [1 10];
            app.stsetSpinner.ValueChangedFcn = createCallbackFcn(app, @stsetSpinnerValueChanged, true);
            app.stsetSpinner.Value = 1;
            app.ndsetSpinnerLabel = uilabel(app.UIFigure);
            app.ndsetSpinnerLabel.Text = '2nd set';
            app.ndsetSpinner = uispinner(app.UIFigure);
            app.ndsetSpinner.Limits = [1 10];
            app.ndsetSpinner.ValueChangedFcn = createCallbackFcn(app, @ndsetSpinnerValueChanged, true);
            app.ndsetSpinner.Value = 2;
            app.stsetSliderLabel = uilabel(app.UIFigure);
            app.stsetSliderLabel.Text = '1st set';
            app.stsetSlider = uislider(app.UIFigure);
            app.stsetSlider.Limits = [0 5000];
            app.stsetSlider.ValueChangedFcn = createCallbackFcn(app, @stsetSliderValueChanged, true);
            app.stsetSlider.Value = 500;
            app.matpathtofileEditFieldLabel = uilabel(app.UIFigure);
            app.matpathtofileEditFieldLabel.Text = '.mat path to file';
            app.matpathtofileEditField = uieditfield(app.UIFigure, 'text');
            app.matpathtofileEditField.ValueChangedFcn = createCallbackFcn(app, @matpathtofileEditFieldValueChanged, true);
            app.ndsetSliderLabel = uilabel(app.UIFigure);
            app.ndsetSliderLabel.Text = '2nd set';
            app.ndsetSlider = uislider(app.UIFigure);
            app.ndsetSlider.Limits = [0 5000];
            app.ndsetSlider.ValueChangedFcn = createCallbackFcn(app, @ndsetSliderValueChanged, true);
            app.ndsetSlider.Value = 500;
            app.RotateButton = uibutton(app.UIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.ZoomButton = uibutton(app.UIFigure, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Text = 'Zoom';
            app.ReinitializeButton = uibutton(app.UIFigure, 'push');
            app.ReinitializeButton.ButtonPushedFcn = createCallbackFcn(app, @ReinitializeButtonPushed, true);
            app.ReinitializeButton.Text = 'Reinitialize';
            app.NumberofneuronstodisplayLabel = uilabel(app.UIFigure);
            app.NumberofneuronstodisplayLabel.Text = 'Number of neurons to display';
            app.CommonpointsmarkersizeSliderLabel = uilabel(app.UIFigure);
            app.CommonpointsmarkersizeSliderLabel.Text = 'Common points markersize';
            app.CommonpointsmarkersizeSlider = uislider(app.UIFigure);
            app.CommonpointsmarkersizeSlider.Limits = [0 50];
            app.CommonpointsmarkersizeSlider.ValueChangedFcn = createCallbackFcn(app, @CommonpointsmarkersizeSliderValueChanged, true);
            app.CommonpointsmarkersizeSlider.Value = 25;
            app.UncommonpointsmarkersizeSliderLabel = uilabel(app.UIFigure);
            app.UncommonpointsmarkersizeSliderLabel.Text = 'Uncommon points markersize';
            app.UncommonpointsmarkersizeSlider = uislider(app.UIFigure);
            app.UncommonpointsmarkersizeSlider.Limits = [0 5];
            app.UncommonpointsmarkersizeSlider.ValueChangedFcn = createCallbackFcn(app, @UncommonpointsmarkersizeSliderValueChanged, true);
            app.UncommonpointsmarkersizeSlider.Value = 2.5;
            app.ChoiceofpointLabel = uilabel(app.UIFigure);
            app.ChoiceofpointLabel.Text = 'Choice of point';
            app.xaxispointSliderLabel = uilabel(app.UIFigure);
            app.xaxispointSliderLabel.Text = 'x-axis point';
            app.xaxispointSlider = uislider(app.UIFigure);
            app.xaxispointSlider.Limits = [0 0.496];
            app.xaxispointSlider.MajorTickLabels = {'0', '', '', '', '0.496'};
            app.xaxispointSlider.ValueChangedFcn = createCallbackFcn(app, @xaxispointSliderValueChanged, true);
            app.xaxispointSlider.Value = 0.248;
            app.yaxispointSliderLabel = uilabel(app.UIFigure);
            app.yaxispointSliderLabel.Text = 'y-axis point';
            app.yaxispointSlider = uislider(app.UIFigure);
            app.yaxispointSlider.Limits = [0 1.122];
            app.yaxispointSlider.MajorTickLabels = {'0', '', '', '', '', '1.122'};
            app.yaxispointSlider.ValueChangedFcn = createCallbackFcn(app, @yaxispointSliderValueChanged, true);
            app.yaxispointSlider.Value = 0.561;
            app.zaxispointSliderLabel = uilabel(app.UIFigure);
            app.zaxispointSliderLabel.Text = 'z-axis point';
            app.zaxispointSlider = uislider(app.UIFigure);
            app.zaxispointSlider.Limits = [0 0.276];
            app.zaxispointSlider.MajorTicks = [0 0.1 0.276];
            app.zaxispointSlider.MajorTickLabels = {'0', '', '0.276'};
            app.zaxispointSlider.ValueChangedFcn = createCallbackFcn(app, @zaxispointSliderValueChanged, true);
            app.zaxispointSlider.Value = 0.138;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.Examplefromset1DropDownLabel = uilabel(app.UIFigure);
            app.Examplefromset1DropDownLabel.Text = 'Example from set 1';
            app.Examplefromset1DropDown = uidropdown(app.UIFigure);
            app.Examplefromset1DropDown.ValueChangedFcn = createCallbackFcn(app, @Examplefromset1DropDownValueChanged, true);
            app.Examplefromset2DropDownLabel = uilabel(app.UIFigure);
            app.Examplefromset2DropDownLabel.Text = 'Example from set 2';
            app.Examplefromset2DropDown = uidropdown(app.UIFigure);
            app.Examplefromset2DropDown.ValueChangedFcn = createCallbackFcn(app, @Examplefromset2DropDownValueChanged, true);
            app.XYButton = uibutton(app.UIFigure, 'push');
            app.XYButton.ButtonPushedFcn = createCallbackFcn(app, @XYButtonPushed, true);
            app.XYButton.Text = 'X-Y';
            app.XZButton = uibutton(app.UIFigure, 'push');
            app.XZButton.ButtonPushedFcn = createCallbackFcn(app, @XZButtonPushed, true);
            app.XZButton.Text = 'X-Z';
            app.YZButton = uibutton(app.UIFigure, 'push');
            app.YZButton.ButtonPushedFcn = createCallbackFcn(app, @YZButtonPushed, true);
            app.YZButton.Text = 'Y-Z';
            app.CorrelationTextAreaLabel = uilabel(app.UIFigure);
            app.CorrelationTextAreaLabel.Text = 'Correlation';
            app.CorrelationTextArea = uitextarea(app.UIFigure);
            app.CorrelationTextArea_2Label = uilabel(app.UIFigure);
            app.CorrelationTextArea_2Label.Text = 'Correlation';
            app.CorrelationTextArea_2 = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = visualizeApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end